classdef ConnectionStabilityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TopLeftPanel                    matlab.ui.container.Panel
        OperatingFrequencykHzEditFieldLabel  matlab.ui.control.Label
        OperatingFrequencykHzEditField  matlab.ui.control.NumericEditField
        ChannelDepthmEditFieldLabel     matlab.ui.control.Label
        ChannelDepthmEditField          matlab.ui.control.NumericEditField
        ProjectorVoltageResponsedBEditFieldLabel  matlab.ui.control.Label
        ProjectorVoltageResponsedBEditField  matlab.ui.control.NumericEditField
        ProjectorDepthmEditFieldLabel   matlab.ui.control.Label
        ProjectorDepthmEditField        matlab.ui.control.NumericEditField
        HydrophoneVoltageSensitivitydB1VPaEditFieldLabel  matlab.ui.control.Label
        HydrophoneVoltageSensitivitydB1VPaEditField  matlab.ui.control.NumericEditField
        HydrophoneDepthmEditFieldLabel  matlab.ui.control.Label
        HydrophoneDepthmEditField       matlab.ui.control.NumericEditField
        RecieverGaindBEditFieldLabel    matlab.ui.control.Label
        RecieverGaindBEditField         matlab.ui.control.NumericEditField
        RecieverNoiseFiguredBEditFieldLabel  matlab.ui.control.Label
        RecieverNoiseFiguredBEditField  matlab.ui.control.NumericEditField
        EstimateConnectionStabilityButton  matlab.ui.control.Button
        EstimateConfidenceRegionButton  matlab.ui.control.Button
        NumberofHydrophonesEditFieldLabel  matlab.ui.control.Label
        NumberofHydrophonesEditField    matlab.ui.control.NumericEditField
        SeperationDistanceEditFieldLabel  matlab.ui.control.Label
        SeperationDistanceEditField     matlab.ui.control.NumericEditField
        OperationalParametersLabel      matlab.ui.control.Label
        ProjectorParametersLabel        matlab.ui.control.Label
        ReceiverParametersLabel         matlab.ui.control.Label
        ReceiverPreampParametersLabel   matlab.ui.control.Label
        CoverageMapParametersLabel      matlab.ui.control.Label
        TopRightPanel                   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
                               
        propSpeed = 1520;
        channelDepth = 200;
        OperatingFrequency = 37.5e3; 
        projector = phased.IsotropicProjector('VoltageResponse',120);
        beaconPlat = phased.Platform('InitialPosition',[5000; 2000; -199],...
  'Velocity',[0; 0; 0]);
        hydrophone1 = phased.IsotropicHydrophone('VoltageSensitivity',-150);
        arrayPlat = phased.Platform('InitialPosition',[5000; 2000; -180],...
  'Velocity',[0; 0; 0]);
        rx = phased.ReceiverPreamp(...
            'Gain',10,...
            'NoiseFigure',20);
        wav;
        projRadiator;
        arrayCollector;
        arrayCollector2;
        arrayPlat2;
        prf;
        isopaths;
        channel;
        fs;
        numPlat = 3;
        distPlat = 50;
        SNRrx;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Define the Underwater Channel
            app.isopaths = phased.IsoSpeedUnderwaterPaths('ChannelDepth',app.channelDepth,...
  'NumPathsSource','Property','NumPaths',10,'PropagationSpeed',app.propSpeed);

            app.channel = phased.MultipathChannel('OperatingFrequency',app.OperatingFrequency);
            
            
            %Define the Acoustic Beacon and Passive Array
            app.prf = .1;
            pulseWidth = 10e-3;
            pulseBandwidth = 1/pulseWidth;
            app.fs = 2*pulseBandwidth;
            app.wav = phased.RectangularWaveform('PRF',app.prf,'PulseWidth',pulseWidth,...
              'SampleRate',app.fs);
            app.channel.SampleRate = app.fs;
            
            app.projector = phased.IsotropicProjector('VoltageResponse',120);

            app.projRadiator = phased.Radiator('Sensor',app.projector,...
              'PropagationSpeed',app.propSpeed,'OperatingFrequency',app.OperatingFrequency);
            
            app.beaconPlat = phased.Platform('InitialPosition',[5000; 2000; -199],...
              'Velocity',[0; 0; 0]);
          
          
            app.hydrophone1 = phased.IsotropicHydrophone('VoltageSensitivity',-150);
            array = phased.ULA('Element',app.hydrophone1,...
              'NumElements',5,'ElementSpacing',app.propSpeed/app.OperatingFrequency/2,...
              'ArrayAxis','y');
            
            app.arrayCollector = phased.Collector('Sensor',array,...
              'PropagationSpeed',app.propSpeed,'OperatingFrequency',app.OperatingFrequency);
            
            app.arrayPlat = phased.Platform('InitialPosition',[5000; 2000; -190],...
              'Velocity',[0; 0; 0]);
          
                   
            app.rx = phased.ReceiverPreamp(...
            'Gain',10,...
            'NoiseFigure',20,...
            'SampleRate',app.fs,...
            'SeedSource','Property',...
            'Seed',2007);

        end

        % Button pushed function: EstimateConnectionStabilityButton
        function EstimateConnectionStabilityButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            
            x = app.wav();
            numTransmits = 10;
            rxsig = zeros(size(x,1),5,numTransmits);
            Vrx = zeros(1,61);
            Trx = zeros(1,61);
            app.SNRrx = zeros(1,61);
            
            
            for j = 2000:20:8000
            
            app.arrayPlat = phased.Platform('InitialPosition',[5000; j; -190],...
              'Velocity',[0; 0; 0]);
            
            for i = 1:numTransmits
            
              % Update array and acoustic beacon positions
              [pos_tx,vel_tx] = app.beaconPlat(1/app.prf);
              [pos_rx,vel_rx] = app.arrayPlat(1/app.prf);
            
              % Compute paths between the acoustic beacon and array
              [paths,dop,aloss,rcvang,srcang] = ...
                  app.isopaths(pos_tx,pos_rx,vel_tx,vel_rx,1/app.prf);
            
              % Propagate the acoustic beacon waveform
              tsig = app.projRadiator(x,srcang);
              rsig = app.channel(tsig,paths,dop,aloss);
              
              % Collect the propagated signal
              rsig = app.arrayCollector(rsig,rcvang);
              
              % Store the received pulses
              rxsig(:,:,i) = abs(app.rx(rsig));
             
            end
            
            [M,I]= (max(rxsig(:,5)));
            Vrx(1,((j-2000)/20)+1) = abs(M);
            Trx(1,((j-2000)/20)+1) = I/200;
            app.SNRrx(1,((j-2000)/20)+1) = Vrx(1,((j-2000)/20)+1)/mean(rxsig(:,5));
            
            end        
                        
%             plot([0 500 1000 1500],10e9*Vrx,'.-','Parent',app.UIAxes)
%             plot([0 500 1000 1500],Trx,'.-','Parent',app.UIAxes_2)
%             plot([0 500 1000 1500],SNRrx,'.-','Parent',app.UIAxes_3)
            
            app.UIAxes.YLim = [0 0.01];
            semilogy([0:20:6000],Vrx,'.-','Parent',app.UIAxes)
            plot([0:20:6000],Trx,'.-','Parent',app.UIAxes_2)
            plot([0:20:6000],app.SNRrx,'.-','Parent',app.UIAxes_3)
            
        end

        % Value changed function: OperatingFrequencykHzEditField
        function OperatingFrequencykHzEditFieldValueChanged(app, event)
            value = app.OperatingFrequencykHzEditField.Value;
            app.OperatingFrequency = value*1000;
        end

        % Value changed function: ChannelDepthmEditField
        function ChannelDepthmEditFieldValueChanged(app, event)
            value = app.ChannelDepthmEditField.Value;
            app.channelDepth = value;
        end

        % Value changed function: 
        % ProjectorVoltageResponsedBEditField
        function ProjectorVoltageResponsedBEditFieldValueChanged(app, event)
            value = app.ProjectorVoltageResponsedBEditField.Value;
            app.projector = phased.IsotropicProjector('VoltageResponse',value);
        end

        % Value changed function: ProjectorDepthmEditField
        function ProjectorDepthmEditFieldValueChanged(app, event)
            value = app.ProjectorDepthmEditField.Value;
            app.beaconPlat = phased.Platform('InitialPosition',[5000; 2000; -value],...
  'Velocity',[0; 0; 0]);
        end

        % Value changed function: 
        % HydrophoneVoltageSensitivitydB1VPaEditField
        function HydrophoneVoltageSensitivitydB1VPaEditFieldValueChanged(app, event)
            value = app.HydrophoneVoltageSensitivitydB1VPaEditField.Value;
            app.hydrophone1 = phased.IsotropicHydrophone('VoltageSensitivity',-value);
        end

        % Value changed function: HydrophoneDepthmEditField
        function HydrophoneDepthmEditFieldValueChanged(app, event)
            value = app.HydrophoneDepthmEditField.Value;
            app.arrayPlat = phased.Platform('InitialPosition',[5000; 2000; -value],...
  'Velocity',[0; 0; 0]);
        end

        % Value changed function: RecieverGaindBEditField
        function RecieverGaindBEditFieldValueChanged(app, event)
            value = app.RecieverGaindBEditField.Value;
            app.rx = phased.ReceiverPreamp('Gain',value);
        end

        % Value changed function: RecieverNoiseFiguredBEditField
        function RecieverNoiseFiguredBEditFieldValueChanged(app, event)
            value = app.RecieverNoiseFiguredBEditField.Value;
            app.rx = phased.ReceiverPreamp('NoiseFigure',value);
        end

        % Value changed function: NumberofHydrophonesEditField
        function NumberofHydrophonesEditFieldValueChanged(app, event)
            value = app.NumberofHydrophonesEditField.Value;
            app.numPlat = value;
        end

        % Value changed function: SeperationDistanceEditField
        function SeperationDistanceEditFieldValueChanged(app, event)
            value = app.SeperationDistanceEditField.Value;
            app.distPlat = value;
        end

        % Button pushed function: EstimateConfidenceRegionButton
        function EstimateConfidenceRegionButtonPushed(app, event)
            cla(app.UIAxes2)
            confiRadius = find(app.SNRrx<50,1)*1.5;
            th = 0:2*pi/app.numPlat:2*pi;
            xunit = app.distPlat * cos(th(1:end-1));
            yunit = app.distPlat * sin(th(1:end-1));
            
            numPoints = 90;
            r = linspace(0, confiRadius, numPoints);                % Define Radius & Radius Gradient Vector
            a = linspace(0, 2*pi, numPoints);                       % Angles (Radians)
            [R,A] = ndgrid(r, a);                                   % Create Grid
            Z = -R;                                                 % Create Gradient Matrix
            [X,Y,Z] = pol2cart(A,R,Z);                              % Convert To Cartesian
            hold(app.UIAxes2,"on")
            for i=1:1:length(xunit)
            surf(app.UIAxes2, X+xunit(i), Y+yunit(i), Z)
            %plot(app.UIAxes2, xunit(i),yunit(i))
            axis(app.UIAxes2,'equal')
            colormap(app.UIAxes2,flipud(autumn))
            shading(app.UIAxes2,'interp')
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {786, 786};
                app.GridLayout.ColumnWidth = {'1x'};
                app.TopRightPanel.Layout.Row = 2;
                app.TopRightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x'};
                app.TopRightPanel.Layout.Row = 1;
                app.TopRightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TopLeftPanel = uipanel(app.GridLayout);
            app.OperatingFrequencykHzEditFieldLabel = uilabel(app.TopLeftPanel);
            app.OperatingFrequencykHzEditFieldLabel.Text = 'Operating Frequency (kHz)';
            app.OperatingFrequencykHzEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.OperatingFrequencykHzEditField.ValueChangedFcn = createCallbackFcn(app, @OperatingFrequencykHzEditFieldValueChanged, true);
            app.OperatingFrequencykHzEditField.Value = 37.5;
            app.ChannelDepthmEditFieldLabel = uilabel(app.TopLeftPanel);
            app.ChannelDepthmEditFieldLabel.Text = 'Channel Depth (m)';
            app.ChannelDepthmEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.ChannelDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelDepthmEditFieldValueChanged, true);
            app.ChannelDepthmEditField.Value = 200;
            app.ProjectorVoltageResponsedBEditFieldLabel = uilabel(app.TopLeftPanel);
            app.ProjectorVoltageResponsedBEditFieldLabel.Text = 'Projector Voltage Response (dB)';
            app.ProjectorVoltageResponsedBEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.ProjectorVoltageResponsedBEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectorVoltageResponsedBEditFieldValueChanged, true);
            app.ProjectorVoltageResponsedBEditField.Value = 150;
            app.ProjectorDepthmEditFieldLabel = uilabel(app.TopLeftPanel);
            app.ProjectorDepthmEditFieldLabel.Text = 'Projector Depth (m)';
            app.ProjectorDepthmEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.ProjectorDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectorDepthmEditFieldValueChanged, true);
            app.ProjectorDepthmEditField.Value = 199;
            app.HydrophoneVoltageSensitivitydB1VPaEditFieldLabel = uilabel(app.TopLeftPanel);
            app.HydrophoneVoltageSensitivitydB1VPaEditFieldLabel.Text = 'Hydrophone Voltage Sensitivity (dB//1V/μPa)';
            app.HydrophoneVoltageSensitivitydB1VPaEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.HydrophoneVoltageSensitivitydB1VPaEditField.ValueChangedFcn = createCallbackFcn(app, @HydrophoneVoltageSensitivitydB1VPaEditFieldValueChanged, true);
            app.HydrophoneVoltageSensitivitydB1VPaEditField.Value = 120;
            app.HydrophoneDepthmEditFieldLabel = uilabel(app.TopLeftPanel);
            app.HydrophoneDepthmEditFieldLabel.Text = 'Hydrophone Depth (m)';
            app.HydrophoneDepthmEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.HydrophoneDepthmEditField.ValueChangedFcn = createCallbackFcn(app, @HydrophoneDepthmEditFieldValueChanged, true);
            app.HydrophoneDepthmEditField.Value = 180;
            app.RecieverGaindBEditFieldLabel = uilabel(app.TopLeftPanel);
            app.RecieverGaindBEditFieldLabel.Text = 'Reciever Gain (dB)';
            app.RecieverGaindBEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.RecieverGaindBEditField.ValueChangedFcn = createCallbackFcn(app, @RecieverGaindBEditFieldValueChanged, true);
            app.RecieverGaindBEditField.Value = 10;
            app.RecieverNoiseFiguredBEditFieldLabel = uilabel(app.TopLeftPanel);
            app.RecieverNoiseFiguredBEditFieldLabel.Text = 'Reciever Noise Figure (dB)';
            app.RecieverNoiseFiguredBEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.RecieverNoiseFiguredBEditField.ValueChangedFcn = createCallbackFcn(app, @RecieverNoiseFiguredBEditFieldValueChanged, true);
            app.RecieverNoiseFiguredBEditField.Value = 20;
            app.EstimateConnectionStabilityButton = uibutton(app.TopLeftPanel, 'push');
            app.EstimateConnectionStabilityButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateConnectionStabilityButtonPushed, true);
            app.EstimateConnectionStabilityButton.Text = 'Estimate Connection Stability';
            app.EstimateConfidenceRegionButton = uibutton(app.TopLeftPanel, 'push');
            app.EstimateConfidenceRegionButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateConfidenceRegionButtonPushed, true);
            app.EstimateConfidenceRegionButton.Text = 'Estimate Confidence Region';
            app.NumberofHydrophonesEditFieldLabel = uilabel(app.TopLeftPanel);
            app.NumberofHydrophonesEditFieldLabel.Text = 'Number of Hydrophones';
            app.NumberofHydrophonesEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.NumberofHydrophonesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofHydrophonesEditFieldValueChanged, true);
            app.NumberofHydrophonesEditField.Value = 3;
            app.SeperationDistanceEditFieldLabel = uilabel(app.TopLeftPanel);
            app.SeperationDistanceEditFieldLabel.Text = 'Seperation Distance ';
            app.SeperationDistanceEditField = uieditfield(app.TopLeftPanel, 'numeric');
            app.SeperationDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @SeperationDistanceEditFieldValueChanged, true);
            app.SeperationDistanceEditField.Value = 50;
            app.OperationalParametersLabel = uilabel(app.TopLeftPanel);
            app.OperationalParametersLabel.Text = 'Operational Parameters';
            app.ProjectorParametersLabel = uilabel(app.TopLeftPanel);
            app.ProjectorParametersLabel.Text = 'Projector Parameters';
            app.ReceiverParametersLabel = uilabel(app.TopLeftPanel);
            app.ReceiverParametersLabel.Text = 'Receiver Parameters';
            app.ReceiverPreampParametersLabel = uilabel(app.TopLeftPanel);
            app.ReceiverPreampParametersLabel.Text = 'Receiver Preamp Parameters';
            app.CoverageMapParametersLabel = uilabel(app.TopLeftPanel);
            app.CoverageMapParametersLabel.Text = 'Coverage Map Parameters';
            app.TopRightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.TopRightPanel);
            title(app.UIAxes, 'Recieved Signal ')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes_2 = uiaxes(app.TopRightPanel);
            title(app.UIAxes_2, 'Time Delay')
            xlabel(app.UIAxes_2, 'Distance')
            ylabel(app.UIAxes_2, 'RTT')
            app.UIAxes2 = uiaxes(app.TopRightPanel);
            title(app.UIAxes2, 'Coverage Map')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes_3 = uiaxes(app.TopRightPanel);
            title(app.UIAxes_3, 'Noise Profile')
            xlabel(app.UIAxes_3, 'Distance')
            ylabel(app.UIAxes_3, 'SNR')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectionStabilityApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end