classdef AUV_MechanicsHullParametricApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AUVMechanicsUIFigure           matlab.ui.Figure
        Logo                           matlab.ui.control.Image
        AUVVehicleMechanicsLabel       matlab.ui.control.Label
        HullParameterExplorationAppLabel  matlab.ui.control.Label
        HullSections                   matlab.ui.control.Image
        HullSectionDimensionsPanel     matlab.ui.container.Panel
        HullDiametermSpinnerLabel      matlab.ui.control.Label
        HullDiametermSpinner           matlab.ui.control.Spinner
        SternCapLengthmSpinnerLabel    matlab.ui.control.Label
        SternCapLengthmSpinner         matlab.ui.control.Spinner
        WallThicknessmSpinnerLabel     matlab.ui.control.Label
        WallThicknessmSpinner          matlab.ui.control.Spinner
        HullLengthmSpinnerLabel        matlab.ui.control.Label
        HullLengthmSpinner             matlab.ui.control.Spinner
        BowCapLengthmSpinnerLabel      matlab.ui.control.Label
        BowCapLengthmSpinner           matlab.ui.control.Spinner
        TunnelThrusterSectionDimensionsPanel  matlab.ui.container.Panel
        TunnelSectionLengthmSpinnerLabel  matlab.ui.control.Label
        TunnelSectionLengthmSpinner    matlab.ui.control.Spinner
        TunnelDiametermSpinnerLabel    matlab.ui.control.Label
        TunnelDiametermSpinner         matlab.ui.control.Spinner
        PropMotorMountSectionDimensionsPanel  matlab.ui.container.Panel
        MotorMountLengthmSpinnerLabel  matlab.ui.control.Label
        MotorMountLengthmSpinner       matlab.ui.control.Spinner
        MotorDiametermSpinnerLabel     matlab.ui.control.Label
        MotorDiametermSpinner          matlab.ui.control.Spinner
        ResetDefaultValuesButton       matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HullDiametermSpinner
        function HullDiametermSpinnerValueChanged(app, event)
            value = app.HullDiametermSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Body','hulldiameter',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: HullLengthmSpinner
        function HullLengthmSpinnerValueChanged(app, event)
            value = app.HullLengthmSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Body','hullsectionlength',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: BowCapLengthmSpinner
        function BowCapLengthmSpinnerValueChanged(app, event)
            value = app.BowCapLengthmSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Body','bowcaplength',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: SternCapLengthmSpinner
        function SternCapLengthmSpinnerValueChanged(app, event)
            value = app.SternCapLengthmSpinner.Value;
            S = evalin('base','Vehicle');
            er = S.Body.sterncapendradius;
            hr = S.Body.hulldiameter/2;
            th = asin(er/hr);
            ma = value/cos(th);
            S = setfield(S,'Body','sterncaplength',value);
            S = setfield(S,'Body','sterncapmajoraxis',ma);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: WallThicknessmSpinner
        function WallThicknessmSpinnerValueChanged(app, event)
            value = app.WallThicknessmSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Body','wallthickness',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TunnelSectionLengthmSpinner
        function TunnelSectionLengthmSpinnerValueChanged(app, event)
            value = app.TunnelSectionLengthmSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Body','tunnelsectionlength',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: TunnelDiametermSpinner
        function TunnelDiametermSpinnerValueChanged(app, event)
            value = app.TunnelDiametermSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Thruster','tunneldiameter',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MotorMountLengthmSpinner
        function MotorMountLengthmSpinnerValueChanged(app, event)
            value = app.MotorMountLengthmSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Prop','motormountlength',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Value changed function: MotorDiametermSpinner
        function MotorDiametermSpinnerValueChanged(app, event)
            value = app.MotorDiametermSpinner.Value;
            S = evalin('base','Vehicle');
            S = setfield(S,'Prop','motordiameter',value);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end

        % Button pushed function: ResetDefaultValuesButton
        function ResetDefaultValuesButtonPushed(app, event)
            S = evalin('base','Vehicle');
            app.HullLengthmSpinner.Value = 0.74;
            S = setfield(S,'Body','hullsectionlength',0.74);
            app.HullDiametermSpinner.Value = 0.292;
            S = setfield(S,'Body','hulldiameter',0.292);
            app.SternCapLengthmSpinner.Value = 0.546;
            S = setfield(S,'Body','sterncaplength',0.546);
            app.BowCapLengthmSpinner.Value = 0.196;
            S = setfield(S,'Body','bowcaplength',0.196);
            app.WallThicknessmSpinner.Value = 0.02;
            S = setfield(S,'Body','wallthickness',0.02);
            app.TunnelSectionLengthmSpinner.Value = 0.14;
            S = setfield(S,'Body','tunnelsectionlength',0.14);
            app.TunnelDiametermSpinner.Value = 0.08;
            S = setfield(S,'Thruster','tunneldiameter',0.08);
            app.MotorMountLengthmSpinner.Value = 0.14;
            S = setfield(S,'Prop','motormountlength',0.14);
            app.MotorDiametermSpinner.Value = 0.04255;
            S = setfield(S,'Prop','motordiameter',0.04255);
            assignin('base','Vehicle',S);
            set_param(gcs,'SimulationCommand','Update');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AUVMechanicsUIFigure = uifigure('Visible', 'off');
            app.AUVMechanicsUIFigure.Name = 'AUV Mechanics';
            app.Logo = uiimage(app.AUVMechanicsUIFigure);
            app.Logo.ImageSource = 'AUVDemoIcon.png';
            app.AUVVehicleMechanicsLabel = uilabel(app.AUVMechanicsUIFigure);
            app.AUVVehicleMechanicsLabel.Text = 'AUV - Vehicle Mechanics';
            app.HullParameterExplorationAppLabel = uilabel(app.AUVMechanicsUIFigure);
            app.HullParameterExplorationAppLabel.Text = 'Hull Parameter Exploration App';
            app.HullSections = uiimage(app.AUVMechanicsUIFigure);
            app.HullSections.ImageSource = 'HullSections.png';
            app.HullSectionDimensionsPanel = uipanel(app.AUVMechanicsUIFigure);
            app.HullSectionDimensionsPanel.Title = 'Hull Section Dimensions';
            app.HullDiametermSpinnerLabel = uilabel(app.HullSectionDimensionsPanel);
            app.HullDiametermSpinnerLabel.Text = 'Hull Diameter (m) :';
            app.HullDiametermSpinner = uispinner(app.HullSectionDimensionsPanel);
            app.HullDiametermSpinner.Step = 0.01;
            app.HullDiametermSpinner.Limits = [0.1 0.5];
            app.HullDiametermSpinner.ValueChangedFcn = createCallbackFcn(app, @HullDiametermSpinnerValueChanged, true);
            app.HullDiametermSpinner.Value = 0.292;
            app.SternCapLengthmSpinnerLabel = uilabel(app.HullSectionDimensionsPanel);
            app.SternCapLengthmSpinnerLabel.Text = 'Stern Cap Length (m) :';
            app.SternCapLengthmSpinner = uispinner(app.HullSectionDimensionsPanel);
            app.SternCapLengthmSpinner.Step = 0.01;
            app.SternCapLengthmSpinner.Limits = [0.3 1];
            app.SternCapLengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @SternCapLengthmSpinnerValueChanged, true);
            app.SternCapLengthmSpinner.Value = 0.5855;
            app.WallThicknessmSpinnerLabel = uilabel(app.HullSectionDimensionsPanel);
            app.WallThicknessmSpinnerLabel.Text = 'Wall Thickness (m) :';
            app.WallThicknessmSpinner = uispinner(app.HullSectionDimensionsPanel);
            app.WallThicknessmSpinner.Step = 0.005;
            app.WallThicknessmSpinner.Limits = [0.005 0.05];
            app.WallThicknessmSpinner.ValueChangedFcn = createCallbackFcn(app, @WallThicknessmSpinnerValueChanged, true);
            app.WallThicknessmSpinner.Value = 0.02;
            app.HullLengthmSpinnerLabel = uilabel(app.HullSectionDimensionsPanel);
            app.HullLengthmSpinnerLabel.Text = 'Hull Length (m) :';
            app.HullLengthmSpinner = uispinner(app.HullSectionDimensionsPanel);
            app.HullLengthmSpinner.Step = 0.05;
            app.HullLengthmSpinner.Limits = [0.5 1];
            app.HullLengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @HullLengthmSpinnerValueChanged, true);
            app.HullLengthmSpinner.Value = 0.75;
            app.BowCapLengthmSpinnerLabel = uilabel(app.HullSectionDimensionsPanel);
            app.BowCapLengthmSpinnerLabel.Text = 'Bow Cap Length (m) :';
            app.BowCapLengthmSpinner = uispinner(app.HullSectionDimensionsPanel);
            app.BowCapLengthmSpinner.Step = 0.01;
            app.BowCapLengthmSpinner.Limits = [0.15 0.3];
            app.BowCapLengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @BowCapLengthmSpinnerValueChanged, true);
            app.BowCapLengthmSpinner.Value = 0.196;
            app.TunnelThrusterSectionDimensionsPanel = uipanel(app.AUVMechanicsUIFigure);
            app.TunnelThrusterSectionDimensionsPanel.Title = 'Tunnel Thruster Section Dimensions';
            app.TunnelSectionLengthmSpinnerLabel = uilabel(app.TunnelThrusterSectionDimensionsPanel);
            app.TunnelSectionLengthmSpinnerLabel.Text = 'Tunnel Section Length (m) :';
            app.TunnelSectionLengthmSpinner = uispinner(app.TunnelThrusterSectionDimensionsPanel);
            app.TunnelSectionLengthmSpinner.Step = 0.01;
            app.TunnelSectionLengthmSpinner.Limits = [0.1 0.25];
            app.TunnelSectionLengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @TunnelSectionLengthmSpinnerValueChanged, true);
            app.TunnelSectionLengthmSpinner.Value = 0.14;
            app.TunnelDiametermSpinnerLabel = uilabel(app.TunnelThrusterSectionDimensionsPanel);
            app.TunnelDiametermSpinnerLabel.Text = 'Tunnel Diameter (m) :';
            app.TunnelDiametermSpinner = uispinner(app.TunnelThrusterSectionDimensionsPanel);
            app.TunnelDiametermSpinner.Step = 0.01;
            app.TunnelDiametermSpinner.Limits = [0.05 0.1];
            app.TunnelDiametermSpinner.ValueChangedFcn = createCallbackFcn(app, @TunnelDiametermSpinnerValueChanged, true);
            app.TunnelDiametermSpinner.Value = 0.08;
            app.PropMotorMountSectionDimensionsPanel = uipanel(app.AUVMechanicsUIFigure);
            app.PropMotorMountSectionDimensionsPanel.Title = 'Prop Motor Mount Section Dimensions';
            app.MotorMountLengthmSpinnerLabel = uilabel(app.PropMotorMountSectionDimensionsPanel);
            app.MotorMountLengthmSpinnerLabel.Text = 'Motor Mount Length (m) :';
            app.MotorMountLengthmSpinner = uispinner(app.PropMotorMountSectionDimensionsPanel);
            app.MotorMountLengthmSpinner.Step = 0.01;
            app.MotorMountLengthmSpinner.Limits = [0.1 0.25];
            app.MotorMountLengthmSpinner.ValueChangedFcn = createCallbackFcn(app, @MotorMountLengthmSpinnerValueChanged, true);
            app.MotorMountLengthmSpinner.Value = 0.14;
            app.MotorDiametermSpinnerLabel = uilabel(app.PropMotorMountSectionDimensionsPanel);
            app.MotorDiametermSpinnerLabel.Text = 'Motor Diameter (m) :';
            app.MotorDiametermSpinner = uispinner(app.PropMotorMountSectionDimensionsPanel);
            app.MotorDiametermSpinner.Step = 0.01;
            app.MotorDiametermSpinner.Limits = [0.02 0.08];
            app.MotorDiametermSpinner.ValueChangedFcn = createCallbackFcn(app, @MotorDiametermSpinnerValueChanged, true);
            app.MotorDiametermSpinner.Value = 0.04255;
            app.ResetDefaultValuesButton = uibutton(app.AUVMechanicsUIFigure, 'push');
            app.ResetDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultValuesButtonPushed, true);
            app.ResetDefaultValuesButton.Text = 'Reset Default Values';
            % Show the figure after all components are created
            app.AUVMechanicsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_MechanicsHullParametricApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AUVMechanicsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AUVMechanicsUIFigure)
        end
    end
end